classdef FEMGui_Proj2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        aEditFieldLabel                matlab.ui.control.Label
        a                              matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        b                              matlab.ui.control.NumericEditField
        cEditFieldLabel                matlab.ui.control.Label
        c                              matlab.ui.control.NumericEditField
        dEditFieldLabel                matlab.ui.control.Label
        d                              matlab.ui.control.NumericEditField
        ElementTypeDropDownLabel       matlab.ui.control.Label
        ElementType                    matlab.ui.control.DropDown
        DimensionsandNumberofPartitionsLabel  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        eEditFieldLabel                matlab.ui.control.Label
        e                              matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        GenerateButton                 matlab.ui.control.Button
        ElementsEditFieldLabel         matlab.ui.control.Label
        Elements                       matlab.ui.control.NumericEditField
        NodesEditField_2Label          matlab.ui.control.Label
        Nodes                          matlab.ui.control.NumericEditField
        MeshRefinementSliderLabel      matlab.ui.control.Label
        MeshRefinementSlider           matlab.ui.control.Slider
        UIAxes_2                       matlab.ui.control.UIAxes
        Plot                           matlab.ui.control.Button
        ColorbarButtonGroup            matlab.ui.container.ButtonGroup
        Button_2                       matlab.ui.control.ToggleButton
        Button_3                       matlab.ui.control.ToggleButton
        EEditFieldLabel                matlab.ui.control.Label
        EEditField                     matlab.ui.control.NumericEditField
        PreProcessLabel                matlab.ui.control.Label
        VEditFieldLabel                matlab.ui.control.Label
        VEditField                     matlab.ui.control.NumericEditField
        Lamp                           matlab.ui.control.Lamp
        PostProcessLabel               matlab.ui.control.Label
        ProcessLabel                   matlab.ui.control.Label
        DeformationTypeButtonGroup_2   matlab.ui.container.ButtonGroup
        ExtensionButton                matlab.ui.control.ToggleButton
        ExpansionButton                matlab.ui.control.ToggleButton
        ShearButton                    matlab.ui.control.ToggleButton
        Image2                         matlab.ui.control.Image
        Image2_2                       matlab.ui.control.Image
        Image2_3                       matlab.ui.control.Image
        DropDown                       matlab.ui.control.DropDown
        ComputationTimesLabel          matlab.ui.control.Label
        ComputationTimesEditField      matlab.ui.control.NumericEditField
        ResetButton                    matlab.ui.control.Button
        ResetButton_3                  matlab.ui.control.Button
        EdgeDisplaySwitchLabel         matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        GaussPointsDropDownLabel       matlab.ui.control.Label
        GaussPointsDropDown            matlab.ui.control.DropDown
        VEditFieldLabel_2              matlab.ui.control.Label
        VEditField_2                   matlab.ui.control.NumericEditField
        ELabel                         matlab.ui.control.Label
        EEditField_2                   matlab.ui.control.NumericEditField
        MatrixLabel                    matlab.ui.control.Label
        InclusionLabel                 matlab.ui.control.Label
        MaterialPropertiesLabel        matlab.ui.control.Label
        ScaleEditFieldLabel            matlab.ui.control.Label
        ScaleEditField                 matlab.ui.control.NumericEditField
        DeformationMagnitudeLabel      matlab.ui.control.Label
        DeformationMagnitudeEditField  matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        PreciseLabel                   matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = public)
        didPlotHappen = 0; % Description
        plotType = 'Extension';
        edgeSwitch = 0;
    end
    
    %%%
    methods (Access = public)
        
        function plot_it(app, X_1, Y_1, stress_xx, stress_xy, stress_yx, stress_yy, strain_xx, ...
                strain_xy, strain_yx, strain_yy, disp_x, disp_y);
                                        
            if(app.Switch.Value == '1')
                switch app.plotType
                    case 'stress_xx'
                        patch(app.UIAxes_2, X_1,Y_1,stress_xx,'FaceColor','interp');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Stress_xx']);
                                                
                    case 'stress_xy'
                        patch(app.UIAxes_2, X_1,Y_1,stress_xy,'FaceColor','interp');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Stress_xy']);
                    case 'stress_yx'
                        patch(app.UIAxes_2, X_1,Y_1,stress_yx,'FaceColor','interp');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Stress_yx']);
                    case 'stress_yy'
                        patch(app.UIAxes_2, X_1,Y_1,stress_yy,'FaceColor','interp');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Stress_yy']); 
                    case 'strain_xx'
                        patch(app.UIAxes_2, X_1,Y_1,strain_xx,'FaceColor','interp');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Strain_xx']);  
                    case 'strain_xy'
                        patch(app.UIAxes_2, X_1,Y_1,strain_xy,'FaceColor','interp');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Strain_xy']);   
                    case 'strain_yx'
                        patch(app.UIAxes_2, X_1,Y_1,strain_yx,'FaceColor','interp');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Strain_yx']);  
                    case 'strain_yy'
                        patch(app.UIAxes_2, X_1,Y_1,strain_yy,'FaceColor','interp');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Strain_yy']);       
                   case 'disp_x'
                        patch(app.UIAxes_2, X_1,Y_1,disp_x,'FaceColor','interp');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Disp_X']);  
                   case 'disp_y'
                        patch(app.UIAxes_2, X_1,Y_1,disp_y,'FaceColor','interp');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Disp_Y']);       
                end
                
            elseif(app.Switch.Value == '0')
                switch app.plotType
                    case 'stress_xx'
                        patch(app.UIAxes_2, X_1,Y_1,stress_xx,'FaceColor','interp', 'EdgeColor', 'none');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Stress_xx']);
                                                
                    case 'stress_xy'
                        patch(app.UIAxes_2, X_1,Y_1,stress_xy,'FaceColor','interp', 'EdgeColor', 'none');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Stress_xy']);
                    case 'stress_yx'
                        patch(app.UIAxes_2, X_1,Y_1,stress_yx,'FaceColor','interp', 'EdgeColor', 'none');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Stress_yx']);
                    case 'stress_yy'
                        patch(app.UIAxes_2, X_1,Y_1,stress_yy,'FaceColor','interp', 'EdgeColor', 'none');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Stress_yy']); 
                    case 'strain_xx'
                        patch(app.UIAxes_2, X_1,Y_1,strain_xx,'FaceColor','interp', 'EdgeColor', 'none');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Strain_xx']);  
                    case 'strain_xy'
                        patch(app.UIAxes_2, X_1,Y_1,strain_xy,'FaceColor','interp', 'EdgeColor', 'none');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Strain_xy']);   
                    case 'strain_yx'
                        patch(app.UIAxes_2, X_1,Y_1,strain_yx,'FaceColor','interp', 'EdgeColor', 'none');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Strain_yx']); 
                    case 'strain_yy'
                        patch(app.UIAxes_2, X_1,Y_1,strain_yy,'FaceColor','interp', 'EdgeColor', 'none');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Strain_yy']);      
                   case 'disp_x'
                        patch(app.UIAxes_2, X_1,Y_1,disp_x,'FaceColor','interp', 'EdgeColor', 'none');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Disp_X']);  
                   case 'disp_y'
                        patch(app.UIAxes_2, X_1,Y_1,disp_y,'FaceColor','interp', 'EdgeColor', 'none');
                        colormap(app.UIAxes_2, 'jet');
                        colorbar(app.UIAxes_2);
                        xlabel(app.UIAxes_2, 'X Coordinates');
                        ylabel(app.UIAxes_2, 'Y Coordinates');
                        title(app.UIAxes_2, ['Disp_Y']);       
                end
                    
            end
%             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            %Our mesh function code goes here
            %Note that the way the nodes are numbered here are different
            %(it goes in increasing order from left to right, bottom to
            %top
            %NL = [0 0; 1 0; 2 0; 0 1; 1 1; 2 1; 0 2; 1 2; 2 2]; %coordinates of our points
            %EL = [1 2 5 4; 2 3 6 5; 4 5 8 7; 5 6 9 8];
            app.UIAxes.cla;
            
            [NL, EL] = mesh_it(app.a.Value, app.b.Value, round(app.c.Value), round(app.d.Value), app.e.Value, app.ElementType.Value);
%             
%             if(app.ElementType.Value == "2DQU4N")
%                 [NL, EL] = mesh_2(app.a.Value, app.b.Value, app.c.Value, app.d.Value, app.e.Value);
%             elseif(app.ElementType.Value == "2DTR3N")
%                 [NL, EL] = mesh_mk_trig_2(app.a.Value, app.b.Value, app.c.Value, app.d.Value, app.e.Value);
%             end
%             
            NoN = size(NL, 1);
            NoE = size(EL, 1);
            NPE = size(EL, 2);
            
            if NPE==9
                for i = 1:NoE
                    nl = EL(i, 1:NPE-1);
                    nl = [nl(1) nl(5) nl(2) nl(6) nl(3) nl(7) nl(4) nl(8)];
                    for j = 1:NPE-1
                        x(j,i) = NL(nl(j), 1);
                        y(j,i) = NL(nl(j), 2);
                    end
                end
            elseif NPE==8
                for i = 1:NoE
                    nl = EL(i, 1:NPE);
                    nl = [nl(1) nl(5) nl(2) nl(6) nl(3) nl(7) nl(4) nl(8)];
                    for j = 1:NPE
                        x(j,i) = NL(nl(j), 1);
                        y(j,i) = NL(nl(j), 2);
                    end
                end
            elseif NPE==6
                for i = 1:NoE
                    nl = EL(i, 1:NPE);
                    nl = [nl(1) nl(4) nl(2) nl(5) nl(3) nl(6)];
                    for j = 1:NPE
                        x(j,i) = NL(nl(j), 1);
                        y(j,i) = NL(nl(j), 2);
                        
                    end
                end
            else
                for i = 1:NoE
                    nl = EL(i, 1:NPE);
                    for j = 1:NPE
                        x(j,i) = NL(nl(j), 1);
                        y(j,i) = NL(nl(j), 2);
                        
                    end
                end
            end


            z = zeros(size(x,1), size(x,2));
            
            patch(app.UIAxes, x, y, z, 'FaceColor', 'none');
            
            app.Elements.Value = NoE;
            app.Nodes.Value = NoN;
            
            
            save('EL.mat', 'EL');
            save('NL.mat', 'NL');
            save('x.mat', 'x');
            save('y.mat', 'y');
            
        end

        % Value changed function: MeshRefinementSlider
        function MeshRefinementSliderValueChanged(app, event)
            
            val=round(app.MeshRefinementSlider.Value);
            app.MeshRefinementSlider.Value=val;
        
            app.c.Value = app.MeshRefinementSlider.Value;
            app.d.Value = app.MeshRefinementSlider.Value;
            
            
            %Clear UIAxes
            app.UIAxes.cla;
            %app.UIAxes_2.cla;
            
            [NL, EL] = mesh_it(app.a.Value, app.b.Value, round(app.c.Value), round(app.d.Value), app.e.Value, app.ElementType.Value);
            
              
            NoN = size(NL, 1);
            NoE = size(EL, 1);
            NPE = size(EL, 2);
            
            if NPE==9
                for i = 1:NoE
                    nl = EL(i, 1:NPE-1);
                    nl = [nl(1) nl(5) nl(2) nl(6) nl(3) nl(7) nl(4) nl(8)];
                    for j = 1:NPE-1
                        x(j,i) = NL(nl(j), 1);
                        y(j,i) = NL(nl(j), 2);
                    end
                end
            elseif NPE==8
                for i = 1:NoE
                    nl = EL(i, 1:NPE);
                    nl = [nl(1) nl(5) nl(2) nl(6) nl(3) nl(7) nl(4) nl(8)];
                    for j = 1:NPE
                        x(j,i) = NL(nl(j), 1);
                        y(j,i) = NL(nl(j), 2);
                    end
                end
            elseif NPE==6
                for i = 1:NoE
                    nl = EL(i, 1:NPE);
                    nl = [nl(1) nl(4) nl(2) nl(5) nl(3) nl(6)];
                    for j = 1:NPE
                        x(j,i) = NL(nl(j), 1);
                        y(j,i) = NL(nl(j), 2);
                        
                    end
                end
            else
                for i = 1:NoE
                    nl = EL(i, 1:NPE);
                    for j = 1:NPE
                        x(j,i) = NL(nl(j), 1);
                        y(j,i) = NL(nl(j), 2);
                        
                    end
                end
            end
            
            
            z = zeros(size(x,1), size(x,2));
            
            patch(app.UIAxes, x, y, z, 'FaceColor', 'none');
            
            app.Elements.Value = NoE;
            app.Nodes.Value = NoN;
% %             
%             
%             %%% This part does the mesh for the coloured graph
%             save('EL.mat', 'EL');
%             save('NL.mat', 'NL');
%             save('x.mat', 'x');
%             save('y.mat', 'y');
%             
%             PlotButtonPushed(app, event);

        end

        % Button pushed function: Plot
        function PlotButtonPushed(app, event)
                        
            app.Lamp.Color = 'green';
            pause(0.01)
            app.didPlotHappen = 1;
%             load('EL.mat', 'EL');
%             load('NL.mat', 'NL');
%             load('x.mat', 'x');
%             load('y.mat', 'y');
            %           
            [NL, EL] = mesh_it(app.a.Value, app.b.Value,...
                round(app.c.Value), round(app.d.Value), app.e.Value, app.ElementType.Value);
                          
            NoN = size(NL, 1);
            NoE = size(EL, 1);
            NPE = size(EL, 2);
            
           if NPE==9
                for i = 1:NoE
                    nl = EL(i, 1:NPE-1);
                    nl = [nl(1) nl(5) nl(2) nl(6) nl(3) nl(7) nl(4) nl(8)];
                    for j = 1:NPE-1
                        x(j,i) = NL(nl(j), 1);
                        y(j,i) = NL(nl(j), 2);
                    end
                end
            elseif NPE==8
                for i = 1:NoE
                    nl = EL(i, 1:NPE);
                    nl = [nl(1) nl(5) nl(2) nl(6) nl(3) nl(7) nl(4) nl(8)];
                    for j = 1:NPE
                        x(j,i) = NL(nl(j), 1);
                        y(j,i) = NL(nl(j), 2);
                    end
                end
            elseif NPE==6
                for i = 1:NoE
                    nl = EL(i, 1:NPE);
                    nl = [nl(1) nl(4) nl(2) nl(5) nl(3) nl(6)];
                    for j = 1:NPE
                        x(j,i) = NL(nl(j), 1);
                        y(j,i) = NL(nl(j), 2);
                        
                    end
                end
            else
                for i = 1:NoE
                    nl = EL(i, 1:NPE);
                    for j = 1:NPE
                        x(j,i) = NL(nl(j), 1);
                        y(j,i) = NL(nl(j), 2);
                        
                    end
                end
            end
            
            
            z = zeros(size(x,1), size(x,2));
            
            app.UIAxes.cla;
            patch(app.UIAxes, x, y, z, 'FaceColor', 'none');
            
            app.Elements.Value = NoE;
            app.Nodes.Value = NoN;

            
            app.UIAxes_2.cla;
            
            %initialize plotting variables
            NoN = size(NL, 1);
            NoE = size(EL, 1);
            NPE = size(EL, 2);
            BC_flag = app.DeformationTypeButtonGroup_2.SelectedObject.Text;
            V = app.VEditField.Value; %Material Properties
            E = app.EEditField.Value; %Material Properties
            def_mag = app.DeformationMagnitudeEditField.Value;
            scale = app.ScaleEditField.Value; %Deformation BC
            app.plotType = app.DropDown.Value;
            
            
            switch app.GaussPointsDropDown.Value
                  case '1'
                      GPE = 1;
                  case '3'
                      GPE = 3;
                  case '4'
                      GPE = 4;
                  case '9'
                      GPE = 9;
                      
            end
            
            tic
            
            [NL, EL, ENL] = FEM(NL, EL, BC_flag, E, V, GPE, def_mag);
            ENL;
            [ X_1, Y_1, stress_xx, stress_xy, stress_yx,stress_yy ...
             ,strain_xx,strain_xy,strain_yx,strain_yy, disp_x,disp_y] =...
             post_process(NL, EL, ENL,scale);
            
            
            save('NL.mat', 'NL');
            save('EL.mat', 'EL');
            save('ENL.mat', 'ENL');
            save('X_1.mat', 'X_1');
            save('Y_1.mat', 'Y_1');
            save('stress_xx.mat', 'stress_xx');
            save('stress_xy.mat', 'stress_xy');
            save('stress_yx.mat', 'stress_yx');
            save('stress_yy.mat', 'stress_yy');
            save('strain_xx.mat', 'strain_xx');
            save('strain_xy.mat', 'strain_xy');
            save('strain_yx.mat', 'strain_yx');
            save('strain_yy.mat', 'strain_yy');
            save('disp_x.mat','disp_x');
            save('disp_y.mat','disp_y');
            
            plot_it(app, X_1, Y_1, stress_xx, stress_xy, stress_yx, stress_yy, strain_xx, ...
                strain_xy, strain_yx, strain_yy, disp_x, disp_y);
            
            app.ComputationTimesEditField.Value=toc;
            app.Lamp.Color = 'red';
            
            
        end

        % Selection changed function: ColorbarButtonGroup
        function ColorbarButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColorbarButtonGroup.SelectedObject;
            switch app.ColorbarButtonGroup.SelectedObject.Icon
                case 'jet.png'
                    colormap(app.UIAxes_2, 'jet');
                case 'parula.png'
                    colormap(app.UIAxes_2, 'parula');
            end
        end

        % Value changed function: ElementType
        function ElementTypeValueChanged(app, event)
%             GenerateButtonPushed(app, event);
%             PlotButtonPushed(app, event);
              switch app.ElementType.Value
                  case '2DTR3N'
                      app.GaussPointsDropDown.Items = {'1'};
                  case '2DTR3N-Filled'
                      app.GaussPointsDropDown.Items = {'1'};
                  case '2DQU4N'
                      app.GaussPointsDropDown.Items = {'4'};
                  case '2DQU4N-Filled'
                      app.GaussPointsDropDown.Items = {'4'};
                  case '2DTR6N'
                      app.GaussPointsDropDown.Items = {'3' };
                  case '2DQU8N'
                      app.GaussPointsDropDown.Items = {'4'};    
                  case '2DQU9N'
                      app.GaussPointsDropDown.Items = {'1' '9'};
              end
        end

        % Selection changed function: DeformationTypeButtonGroup_2
        function DeformationTypeButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.DeformationTypeButtonGroup_2.SelectedObject.Text;
            switch selectedButton
                case 'Extension'
                    app.Image.Enable = 'off';
                    app.Image_2.Enable = 'on';
                    app.Image_3.Enable = 'off';
                case 'Expansion'
                    app.Image.Enable = 'on';
                    app.Image_2.Enable = 'off';
                    app.Image_3.Enable = 'off';
                case 'Shear'
                    app.Image.Enable = 'off';
                    app.Image_2.Enable = 'off';
                    app.Image_3.Enable = 'on';
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.plotType = app.DropDown.Value;
            
            %This prevents plot from updating if no values are calculated
            if (app.didPlotHappen) %refer to beginning plotting function
                app.UIAxes_2.cla;
                load('X_1.mat', 'X_1');
                load('Y_1.mat', 'Y_1');
                load('stress_xx.mat', 'stress_xx');
                load('stress_xy.mat', 'stress_xy');
                load('stress_yx.mat', 'stress_yx');
                load('stress_yy.mat', 'stress_yy');
                
                load('strain_xx.mat','strain_xx');
                load('strain_xy.mat','strain_xy');
                load('strain_yx.mat','strain_yx');
                load('strain_yy.mat','strain_yy');
                
                load('disp_x.mat','disp_x');
                load('disp_y.mat','disp_y')
                
                plot_it(app, X_1, Y_1, stress_xx, stress_xy, stress_yx, stress_yy, strain_xx, ...
                strain_xy, strain_yx, strain_yy, disp_x, disp_y);
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UIAxes.cla;
        end

        % Callback function
        function ResetButton_2Pushed(app, event)
            app.UIAxes_3.cla;
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.UIAxes_2.cla;
        end

        % Callback function
        function TestButtonPushed(app, event)
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.edgeSwitch = app.Switch.Value;
            
            if(app.didPlotHappen)
                load('X_1.mat', 'X_1');
                load('Y_1.mat', 'Y_1');
                load('stress_xx.mat', 'stress_xx');
                load('stress_xy.mat', 'stress_xy');
                load('stress_yx.mat', 'stress_yx');
                load('stress_yy.mat', 'stress_yy');
                
                load('strain_xx.mat','strain_xx');
                load('strain_xy.mat','strain_xy');
                load('strain_yx.mat','strain_yx');
                load('strain_yy.mat','strain_yy');
                
                load('disp_x.mat','disp_x');
                load('disp_y.mat','disp_y')
                
                plot_it(app, X_1, Y_1, stress_xx, stress_xy, stress_yx, stress_yy, strain_xx, ...
                strain_xy, strain_yx, strain_yy, disp_x, disp_y);
            end
        end

        % Callback function
        function Button_greenPushed(app, event)
            app.Lamp.Color = 'green';
        end

        % Callback function
        function Button_redPushed(app, event)
            app.Lamp.Color = 'red';
        end

        % Value changed function: GaussPointsDropDown
        function GaussPointsDropDownValueChanged(app, event)
                                    
        end

        % Callback function
        function DeformationMagnitudeEditFieldValueChanged(app, event)
            def_mag = app.ScaleEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.a = uieditfield(app.UIFigure, 'numeric');
            app.a.Value = 1;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.b = uieditfield(app.UIFigure, 'numeric');
            app.b.Value = 1;
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.c = uieditfield(app.UIFigure, 'numeric');
            app.c.Value = 5;
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.d = uieditfield(app.UIFigure, 'numeric');
            app.d.Value = 5;
            app.ElementTypeDropDownLabel = uilabel(app.UIFigure);
            app.ElementTypeDropDownLabel.Text = 'Element Type';
            app.ElementType = uidropdown(app.UIFigure);
            app.ElementType.Items = {'2DTR3N', '2DTR3N-Filled', '2DTR6N', '2DQU4N', '2DQU4N-Filled', '2DQU8N'};
            app.ElementType.ValueChangedFcn = createCallbackFcn(app, @ElementTypeValueChanged, true);
            app.ElementType.Value = '2DTR3N';
            app.DimensionsandNumberofPartitionsLabel = uilabel(app.UIFigure);
            app.DimensionsandNumberofPartitionsLabel.Text = 'Dimensions and Number of Partitions';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'circle.png';
            app.Button.Text = '';
            app.eEditFieldLabel = uilabel(app.UIFigure);
            app.eEditFieldLabel.Text = 'e';
            app.e = uieditfield(app.UIFigure, 'numeric');
            app.e.Value = 0.2;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ElementsEditFieldLabel = uilabel(app.UIFigure);
            app.ElementsEditFieldLabel.Text = '#Elements';
            app.Elements = uieditfield(app.UIFigure, 'numeric');
            app.Elements.Editable = 'off';
            app.NodesEditField_2Label = uilabel(app.UIFigure);
            app.NodesEditField_2Label.Text = '#Nodes';
            app.Nodes = uieditfield(app.UIFigure, 'numeric');
            app.Nodes.Editable = 'off';
            app.MeshRefinementSliderLabel = uilabel(app.UIFigure);
            app.MeshRefinementSliderLabel.Text = 'Mesh Refinement';
            app.MeshRefinementSlider = uislider(app.UIFigure);
            app.MeshRefinementSlider.Limits = [1 51];
            app.MeshRefinementSlider.ValueChangedFcn = createCallbackFcn(app, @MeshRefinementSliderValueChanged, true);
            app.MeshRefinementSlider.Value = 1;
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Visible = 'off';
            app.Plot = uibutton(app.UIFigure, 'push');
            app.Plot.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Plot.Text = 'Run';
            app.ColorbarButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorbarButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorbarButtonGroupSelectionChanged, true);
            app.ColorbarButtonGroup.Title = 'Colorbar';
            app.Button_2 = uitogglebutton(app.ColorbarButtonGroup);
            app.Button_2.Icon = 'jet.png';
            app.Button_2.Text = '';
            app.Button_2.Value = true;
            app.Button_3 = uitogglebutton(app.ColorbarButtonGroup);
            app.Button_3.Icon = 'parula.png';
            app.Button_3.Text = '';
            app.EEditFieldLabel = uilabel(app.UIFigure);
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.UIFigure, 'numeric');
            app.EEditField.Value = 26.6666;
            app.PreProcessLabel = uilabel(app.UIFigure);
            app.PreProcessLabel.Text = 'Pre-Process';
            app.VEditFieldLabel = uilabel(app.UIFigure);
            app.VEditFieldLabel.Text = 'V';
            app.VEditField = uieditfield(app.UIFigure, 'numeric');
            app.VEditField.Value = 0.3;
            app.Lamp = uilamp(app.UIFigure);
            app.PostProcessLabel = uilabel(app.UIFigure);
            app.PostProcessLabel.Text = 'Post-Process';
            app.ProcessLabel = uilabel(app.UIFigure);
            app.ProcessLabel.Text = 'Process';
            app.DeformationTypeButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.DeformationTypeButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @DeformationTypeButtonGroup_2SelectionChanged, true);
            app.DeformationTypeButtonGroup_2.Title = 'Deformation Type';
            app.ExtensionButton = uitogglebutton(app.DeformationTypeButtonGroup_2);
            app.ExtensionButton.Text = 'Extension';
            app.ExtensionButton.Value = true;
            app.ExpansionButton = uitogglebutton(app.DeformationTypeButtonGroup_2);
            app.ExpansionButton.Text = 'Expansion';
            app.ShearButton = uitogglebutton(app.DeformationTypeButtonGroup_2);
            app.ShearButton.Text = 'Shear';
            app.Image2 = uiimage(app.DeformationTypeButtonGroup_2);
            app.Image2.ImageSource = 'Extension.PNG';
            app.Image2_2 = uiimage(app.DeformationTypeButtonGroup_2);
            app.Image2_2.ImageSource = 'Expansion.PNG';
            app.Image2_3 = uiimage(app.DeformationTypeButtonGroup_2);
            app.Image2_3.ImageSource = 'Shear.PNG';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'stress_xx', 'stress_xy', 'stress_yx', 'stress_yy', 'strain_xx', 'strain_xy', 'strain_yx', 'strain_yy', 'disp_x', 'disp_y'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'stress_xx';
            app.ComputationTimesLabel = uilabel(app.UIFigure);
            app.ComputationTimesLabel.Text = 'Computation Time(s)';
            app.ComputationTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComputationTimesEditField.Editable = 'off';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ResetButton_3 = uibutton(app.UIFigure, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.EdgeDisplaySwitchLabel = uilabel(app.UIFigure);
            app.EdgeDisplaySwitchLabel.Text = 'Edge Display';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ItemsData = {'0', '1'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '0';
            app.GaussPointsDropDownLabel = uilabel(app.UIFigure);
            app.GaussPointsDropDownLabel.Text = 'Gauss Points';
            app.GaussPointsDropDown = uidropdown(app.UIFigure);
            app.GaussPointsDropDown.Items = {'1'};
            app.GaussPointsDropDown.ValueChangedFcn = createCallbackFcn(app, @GaussPointsDropDownValueChanged, true);
            app.GaussPointsDropDown.Value = '1';
            app.VEditFieldLabel_2 = uilabel(app.UIFigure);
            app.VEditFieldLabel_2.Visible = 'off';
            app.VEditFieldLabel_2.Text = 'V';
            app.VEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VEditField_2.Visible = 'off';
            app.VEditField_2.Value = 100;
            app.ELabel = uilabel(app.UIFigure);
            app.ELabel.Visible = 'off';
            app.ELabel.Text = 'E';
            app.EEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_2.Visible = 'off';
            app.EEditField_2.Value = 100;
            app.MatrixLabel = uilabel(app.UIFigure);
            app.MatrixLabel.Text = 'Matrix';
            app.InclusionLabel = uilabel(app.UIFigure);
            app.InclusionLabel.Visible = 'off';
            app.InclusionLabel.Text = 'Inclusion';
            app.MaterialPropertiesLabel = uilabel(app.UIFigure);
            app.MaterialPropertiesLabel.Text = 'Material Properties';
            app.ScaleEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditField.Value = 1;
            app.DeformationMagnitudeLabel = uilabel(app.UIFigure);
            app.DeformationMagnitudeLabel.Text = 'Deformation Magnitude';
            app.DeformationMagnitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeformationMagnitudeEditField.Value = 0.1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'----------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------'; ''};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '----------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------';
            app.PreciseLabel = uilabel(app.UIFigure);
            app.PreciseLabel.Text = 'Precise';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'group.JPEG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FEMGui_Proj2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end